classdef proyectoFinalReconocimientoColores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        INTELIGENCIAARTIFICIALCHRISTIANAGUASDAVIDARCOSLabel  matlab.ui.control.Label
        UNIVERSIDADTCNICADEAMBATOLabel  matlab.ui.control.Label
        RECONOCIMIENTODECOLORESLabel    matlab.ui.control.Label
        ACTIVARCMARAButton              matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        cam;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BUSCARButtonPushed(app, event)
            
        end

        % Button pushed function: ACTIVARCMARAButton
        function ACTIVARCMARAButtonPushed(app, event)
            app.cam = webcam;
            wb = waitbar(0,'-','Name','Espera..','CreateCancelBtn','delete(gcbf)');
            ax = app.UIAxes;
            i=0;
            while true
                % Capturar imagen de la cámara web
                recuadro = snapshot(app.cam);
                img = im2gray(recuadro);
            
              
                % Aplicar filtro Gaussiano para suavizar la imagen
                img = imgaussfilt(img, 2);
            
                % Detectar bordes con Canny
                edgesCanny = edge(img, 'canny', [0.1, 0.2]);
            
                % Detectar bordes con Laplaciano
                edgesLaplacian = edge(img, 'log');
            
                % Combinar bordes detectados por Canny y Laplaciano
                edges = edgesCanny | edgesLaplacian;
            
                % Eliminar objetos con tamaño menor a x pixeles
                edges = bwareaopen(edges, 1000);
            
                % Encuadrar objetos
                stats = regionprops(edges, 'BoundingBox','Image');
            
                % Dibujar rectángulos alrededor de los objetos
                imshow(recuadro, 'Parent', ax);
                hold on;
                for j = 1:length(stats)
                    imgTest=imcrop(recuadro,stats(j).BoundingBox);
                    imgTest = imresize(imgTest,[25 25]);
                    imgTest = im2double(imgTest);
                    imgTest= reshape(imgTest,1,[]);
                    YPredicha = myNeuralNetworkFunctionColors(imgTest);
                    [~, indice] = max(YPredicha);
                    clase = indice - 1;
                    etiqueta = clase + 1;
                    Color='color';
                    switch etiqueta
                            case 1
                                Color = "Amarillo";
                            case 2
                                Color = "Azul";
                            case 3
                                Color = "Blanco";
                            case 4
                                Color = "Celeste";
                            case 5
                                Color = "Gris";
                            case 6
                                Color = "Morado";
                            case 7
                                Color = "Naranja";
                            case 8
                                Color = "Negro";
                            case 9
                                Color = "Rojo";
                            case 10
                                Color = "Verde";
                            otherwise
                                app.COLOREditField.Value = "Indeterminado";   
                    end
                    h = rectangle('Position', stats(j).BoundingBox, 'LineWidth', 2, 'EdgeColor', 'g', 'Tag', 'MyRectangle', 'Parent', ax);
                    rect_pos = get(h,'Position');
                    text(rect_pos(1)+rect_pos(3)/2, rect_pos(2)+rect_pos(4)/2,Color , 'Color', 'red', 'FontSize', 14, 'HorizontalAlignment', 'center', 'Parent', ax);
            
                end
                hold off;
                if ~ishandle(wb)
                    break
                else
                    waitbar(i/10,wb,['num: '  num2str(i)]);
                end

            i=i+1;
                pause(0.11);
            end
    clear app.cam;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imagen mostrada por cámara web')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ACTIVARCMARAButton = uibutton(app.UIFigure, 'push');
            app.ACTIVARCMARAButton.ButtonPushedFcn = createCallbackFcn(app, @ACTIVARCMARAButtonPushed, true);
            app.ACTIVARCMARAButton.Text = 'ACTIVAR CÁMARA';
            app.RECONOCIMIENTODECOLORESLabel = uilabel(app.UIFigure);
            app.RECONOCIMIENTODECOLORESLabel.Text = {'RECONOCIMIENTO DE COLORES'; ''};
            app.UNIVERSIDADTCNICADEAMBATOLabel = uilabel(app.UIFigure);
            app.UNIVERSIDADTCNICADEAMBATOLabel.Text = 'UNIVERSIDAD TÉCNICA DE AMBATO';
            app.INTELIGENCIAARTIFICIALCHRISTIANAGUASDAVIDARCOSLabel = uilabel(app.UIFigure);
            app.INTELIGENCIAARTIFICIALCHRISTIANAGUASDAVIDARCOSLabel.Text = {'INTELIGENCIA ARTIFICIAL'; 'CHRISTIAN AGUAS - DAVID ARCOS'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyectoFinalReconocimientoColores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end